classdef Tello < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DAnimationButton        matlab.ui.control.Button
        TextArea                matlab.ui.control.TextArea
        OpenascriptButton       matlab.ui.control.Button
        TabGroup                matlab.ui.container.TabGroup
        GrapthTab               matlab.ui.container.Tab
        SaveButton              matlab.ui.control.Button
        StartButton             matlab.ui.control.Button
        StopButton_2            matlab.ui.control.Button
        ClearButton             matlab.ui.control.Button
        DataValuesPanel         matlab.ui.container.Panel
        VelocityCheckBox        matlab.ui.control.CheckBox
        HeightCheckBox          matlab.ui.control.CheckBox
        HeightLabel             matlab.ui.control.Label
        OrientationLabel        matlab.ui.control.Label
        SpeedLabel              matlab.ui.control.Label
        YawCheckBox             matlab.ui.control.CheckBox
        PitchCheckBox           matlab.ui.control.CheckBox
        ZCheckBox               matlab.ui.control.CheckBox
        YCheckBox               matlab.ui.control.CheckBox
        RollCheckBox            matlab.ui.control.CheckBox
        XCheckBox               matlab.ui.control.CheckBox
        Graph                   matlab.ui.control.UIAxes
        CameraStreamTab         matlab.ui.container.Tab
        StopButton              matlab.ui.control.Button
        StreamButton            matlab.ui.control.Button
        CameraCanvas            matlab.ui.control.UIAxes
        DronesInformationPanel  matlab.ui.container.Panel
        LandButton              matlab.ui.control.Button
        DisconnectButton        matlab.ui.control.Button
        CameraLabel             matlab.ui.control.Label
        CameraTextArea          matlab.ui.control.TextArea
        BatteryLevelLabel       matlab.ui.control.Label
        BatteryTextArea         matlab.ui.control.TextArea
        StateLabel              matlab.ui.control.Label
        StateTextArea           matlab.ui.control.TextArea
        IDLabel                 matlab.ui.control.Label
        IDTextArea              matlab.ui.control.TextArea
        NameLabel               matlab.ui.control.Label
        NameTextArea            matlab.ui.control.TextArea
        TakeoffButton           matlab.ui.control.Button
        ConnectButton_2         matlab.ui.control.Button
    end

 properties (Access = public)
        tello; % Description
        time;
        running;
        backgroundtimer;
        %Camera
        streamon;
        imageObject;
        cam;
        %Files
        picturepathfile;
        fullpathname;
        file;
        path;   
        %Orientation
        orientation;
        RollLine;
        PitchLine;
        YawLine;
        %Velocity   
        speed;
        speedLine;
        xLine;
        yLine;
        zLine;
        xchecked;
        ychecked;
        zchecked;
        %Height
        heightLine;
        readheight;
        height
        previousheight = 0;
    end
     
    methods (Access = public)
        function updateControlPanel(app,~,~)
        %Loading infos to Control Panel
        app.NameTextArea.Value = app.tello.name;
        app.CameraTextArea.Value = app.tello.AvailableCameras;
        app.NameTextArea.Value = app.tello.name;
        app.CameraTextArea.Value = app.tello.AvailableCameras;   
        app.IDTextArea.Value = app.tello.id;
            %updating battery level and state of drone
            while true
                try 
                battery = num2str(app.tello.BatteryLevel);
                app.BatteryTextArea.Value = [battery,'%'];
                app.StateTextArea.Value = app.tello.State;            
                pause(1);
                catch 
                    %if program is terminated stops the loop
                    break;
                end          
            end
        end
        
        function addpoint(app,~,~)
               %Orientation(1) = Yaw;
               %Orientation(2) = Pitch;
               %Orientation(3) = Roll;
               %Orienatation = (Z,Y,X); 
               app.orientation = rad2deg(readOrientation(app.tello));
               app.speed  = readSpeed(app.tello);
               app.readheight = readHeight(app.tello);
               
               if isempty(app.orientation) == false
                   %if checkbox is ticked
                   %loop to addpoints to animated lines
                   
                   if app.RollCheckBox.Value == true                                                    
                        tStamp = toc(app.time);
                        roll = app.orientation(3);
                        addpoints(app.RollLine, tStamp, roll);                                                                         
                   end
                   
                   if app.PitchCheckBox.Value == true                 
                        tStamp = toc(app.time);
                        addpoints(app.PitchLine, tStamp, app.orientation(2)); 
                   end
                   if app.YawCheckBox.Value == true           
                        tStamp = toc(app.time);
                        addpoints(app.YawLine, tStamp, app.orientation(1));  
                   end
               end
               
               
               if isempty(app.speed) == false    
                   if app.VelocityCheckBox.Value     
                     x = app.speed(1);
                     y = app.speed(2);
                     z = app.speed(3);
                     velocity = sqrt(x^2 + y^2 + z^2);
                     tStamp = toc(app.time);
                     addpoints(app.speedLine, tStamp, velocity);      
                    end
                  
                   if app.XCheckBox.Value 
                     tStamp = toc(app.time);
                     addpoints(app.xLine, tStamp, app.speed(1));
                   end       

                   if app.YCheckBox.Value 
                     tStamp = toc(app.time);
                     addpoints(app.yLine, tStamp, app.speed(2));
                     
                   end
                   
                   if app.ZCheckBox.Value 
                     tStamp = toc(app.time);
                     addpoints(app.zLine, tStamp, app.speed(3));
                   end
               end
               
               if isempty(app.readheight) == false
                  if app.HeightCheckBox.Value == true
                     tStamp = toc(app.time);
                     addpoints(app.heightLine, tStamp, app.readheight)                        
                  end   
               end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RollLine = animatedline(app.Graph,'Color', 'r', 'LineWidth', 2);
            app.PitchLine = animatedline(app.Graph,'Color', 'g', 'LineWidth', 2);
            app.YawLine = animatedline(app.Graph,'Color', 'b', 'LineWidth', 2);
            app.speedLine = animatedline(app.Graph,'Color', 'yellow', 'LineWidth', 2);
            app.xLine = animatedline(app.Graph,'Color', 'r', 'LineWidth', 2);
            app.yLine = animatedline(app.Graph,'Color', 'g', 'LineWidth', 2);
            app.zLine = animatedline(app.Graph,'Color', 'b', 'LineWidth', 2);
            app.heightLine = animatedline(app.Graph,'Color', 'cyan', 'LineWidth', 2);
            
            app.backgroundtimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 0.5, ...                     % Period is 0.5 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.addpoint);      % Specify callback function
        end

        % Button pushed function: StreamButton
        function StreamButtonPushed(app, event)
        %Camera settings
        if isempty(app.cam) 
        app.cam = camera(app.tello);
        end
        app.imageObject = image(app.CameraCanvas);
        axis(app.CameraCanvas,'ij')
        res = split(app.cam.Resolution,'x');
        app.CameraCanvas.XLim = [0,str2double(res{1})];
        app.CameraCanvas.YLim = [0,str2double(res{2})];
        app.cam.preview(app.imageObject);
  
        end

        % Button pushed function: ConnectButton_2
        function ConnectButton_2Pushed(app, event)
             app.tello = ryze();
             %Loading infos to Control Panel
             app.updateControlPanel();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear app.cam;
            cla(app.CameraCanvas);
        end

        % Button pushed function: OpenascriptButton
        function OpenascriptButtonPushed(app, event)
        [app.file,app.path] = uigetfile('*.m', 'MultiSelect', 'off');
        % Focus on UiFigure to not swap windows
        drawnow;
        figure(app.UIFigure);
        %fullpathname
        app.fullpathname = strcat(app.path,app.file);
        app.TextArea.Value = app.fullpathname;
        
        end

        % Button pushed function: TakeoffButton
        function TakeoffButtonPushed(app, event)
            takeoff(app.tello);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
      app.time = tic;
      if strcmp(app.backgroundtimer.Running, 'off')
         app.time = tic;
         start(app.backgroundtimer);
      end   
      
      if isempty(app.fullpathname) == false  
              run(app.fullpathname);
      end
          
      
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
        % Stop the plot
            stop(app.backgroundtimer);
            app.RollCheckBox.Value = false;
            app.PitchCheckBox.Value = false;
            app.YawCheckBox.Value = false;
            app.HeightCheckBox.Value = false;
            app.XCheckBox.Value = false;
            app.YCheckBox.Value = false;
            app.ZCheckBox.Value = false;
            app.VelocityCheckBox.Value = false;
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
        clear app.tello;
        if ~isempty(app.tello)
            app.tello = [];
            app.NameTextArea.Value = ('');
            app.CameraTextArea.Value = ('');
            app.NameTextArea.Value = ('');
            app.CameraTextArea.Value = ('');   
            app.IDTextArea.Value = ('');
            app.BatteryTextArea.Value = ('');
            app.StateTextArea.Value = (''); 
        end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
        clearpoints(app.RollLine);
        clearpoints(app.PitchLine);  
        clearpoints(app.YawLine);
        clearpoints(app.xLine);
        clearpoints(app.yLine);
        clearpoints(app.zLine);
        clearpoints(app.speedLine);
        clearpoints(app.heightLine);
        xlabel(app.Graph,'');
        ylabel(app.Graph,'');
        legend(app.Graph,'');
        end

        % Callback function
        function EmergencyStopButtonPushed(app, event)
        abort(app.tello);
        end

        % Value changed function: RollCheckBox
        function RollCheckBoxValueChanged(app, event)
            if app.RollCheckBox.Value

            xlabel(app.Graph,'Time (in s)');
            ylabel(app.Graph,'Orientation (in degrees)');
            legend(app.Graph, [app.RollLine,app.PitchLine,app.YawLine], 'Roll','Pitch','Yaw');
           
            else
               xlabel(app.Graph,'');
               ylabel(app.Graph,'');
               legend(app.Graph,'');
            end
            
        end

        % Value changed function: PitchCheckBox
        function PitchCheckBoxValueChanged(app, event)
            if app.PitchCheckBox.Value

               xlabel(app.Graph,'Time (in s)');
               ylabel(app.Graph,'Orientation (in degrees)');
               legend(app.Graph, [app.RollLine,app.PitchLine,app.YawLine], 'Roll','Pitch','Yaw');
            else
               xlabel(app.Graph,'');
               ylabel(app.Graph,'');
               legend(app.Graph,'');
            end
            
        end

        % Value changed function: YawCheckBox
        function YawCheckBoxValueChanged(app, event)
           if app.YawCheckBox.Value
              
               xlabel(app.Graph,'Time (in s)');
               ylabel(app.Graph,'Orientation (in degrees)');
               legend(app.Graph, [app.RollLine,app.PitchLine,app.YawLine], 'Roll','Pitch','Yaw');
            else
               xlabel(app.Graph,'');
               ylabel(app.Graph,'');
               legend(app.Graph,'');
            end
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
           %datatype
           filter = {'*.png'};
           [app.file, app.path] = uiputfile(filter);
           %focus on app
           drawnow;
           figure(app.UIFigure);
           if isequal(app.file,0) || isequal(app.path,0)
               app.file = '';
               app.path = '';
           else
               app.picturepathfile = strcat(app.path,app.file);
               exportgraphics(app.Graph,app.picturepathfile);
           
           end       
        end

        % Value changed function: VelocityCheckBox
        function VelocityCheckBoxValueChanged(app, event)
            if app.VelocityCheckBox.Value
                xlabel(app.Graph,'Time (in s)');
                ylabel(app.Graph,'Velocity in m/s');
                legend(app.Graph,[app.xLine,app.yLine,app.zLine,app.speedLine], 'X', 'Y','Z','Velocity');
            else
               xlabel(app.Graph,'');
               ylabel(app.Graph,'');
               legend(app.Graph,'');
            end

        end

        % Value changed function: XCheckBox
        function XCheckBoxValueChanged(app, event)
            if app.XCheckBox.Value
                app.xchecked = true;
                xlabel(app.Graph,'Time (in s)');
                ylabel(app.Graph,'Velocity in m/s');
                legend(app.Graph,[app.xLine,app.yLine,app.zLine,app.speedLine], 'X', 'Y','Z','Velocity');
            else
               xlabel(app.Graph,'');
               ylabel(app.Graph,'');
               legend(app.Graph,'');
            end
        end

        % Value changed function: YCheckBox
        function YCheckBoxValueChanged(app, event)
             if app.YCheckBox.Value
                app.ychecked = true;
                xlabel(app.Graph,'Time (in s)');
                ylabel(app.Graph,'Velocity in m/s');
                legend(app.Graph,[app.xLine,app.yLine,app.zLine,app.speedLine], 'X', 'Y','Z','Velocity');
             else
               xlabel(app.Graph,'');
               ylabel(app.Graph,'');
               legend(app.Graph,'');
             end
        end

        % Value changed function: ZCheckBox
        function ZCheckBoxValueChanged(app, event)
            if app.ZCheckBox.Value
                app.zchecked = true;
                xlabel(app.Graph,'Time (in s)');
                ylabel(app.Graph,'Velocity in m/s');
                legend(app.Graph,[app.xLine,app.yLine,app.zLine,app.speedLine], 'X', 'Y','Z','Velocity');
            else
               xlabel(app.Graph,'');
               ylabel(app.Graph,'');
               legend(app.Graph,'');    
            end 
        end

        % Value changed function: HeightCheckBox
        function HeightCheckBoxValueChanged(app, event)
            if app.HeightCheckBox.Value

               xlabel(app.Graph,'Time (in s)');
               ylabel(app.Graph,'Height in (m)');
               legend(app.Graph,[app.heightLine], 'Height');
            else
               xlabel(app.Graph,'');
               ylabel(app.Graph,'');
               legend(app.Graph,'');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.backgroundtimer);
            clear app.tello;
            delete(app.backgroundtimer);
            delete(app)
        end

        % Callback function
        function FlightScriptButtonPushed(app, event)
      app.time = tic;
      if strcmp(app.backgroundtimer.Running, 'off')
         start(app.backgroundtimer);
      end
      takeoff(app.tello);
      land(app.tello);
      
        end

        % Button pushed function: DAnimationButton
        function DAnimationButtonPushed(app, event)
             run("animation.m");    
        end

        % Button pushed function: LandButton
        function LandButtonPushed2(app, event)
            land(app.tello);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DronesInformationPanel = uipanel(app.UIFigure);
            app.DronesInformationPanel.Title = 'Drone''s Information';
            app.ConnectButton_2 = uibutton(app.DronesInformationPanel, 'push');
            app.ConnectButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_2Pushed, true);
            app.ConnectButton_2.Text = 'Connect';
            app.TakeoffButton = uibutton(app.DronesInformationPanel, 'push');
            app.TakeoffButton.ButtonPushedFcn = createCallbackFcn(app, @TakeoffButtonPushed, true);
            app.TakeoffButton.Text = 'Take off';
            app.NameTextArea = uitextarea(app.DronesInformationPanel);
            app.NameTextArea.Editable = 'off';
            app.NameLabel = uilabel(app.DronesInformationPanel);
            app.NameLabel.Text = 'Name';
            app.IDTextArea = uitextarea(app.DronesInformationPanel);
            app.IDTextArea.Editable = 'off';
            app.IDLabel = uilabel(app.DronesInformationPanel);
            app.IDLabel.Text = 'ID';
            app.StateTextArea = uitextarea(app.DronesInformationPanel);
            app.StateTextArea.Editable = 'off';
            app.StateLabel = uilabel(app.DronesInformationPanel);
            app.StateLabel.Text = 'State';
            app.BatteryTextArea = uitextarea(app.DronesInformationPanel);
            app.BatteryTextArea.Editable = 'off';
            app.BatteryLevelLabel = uilabel(app.DronesInformationPanel);
            app.BatteryLevelLabel.Text = 'Battery Level';
            app.CameraTextArea = uitextarea(app.DronesInformationPanel);
            app.CameraTextArea.Editable = 'off';
            app.CameraLabel = uilabel(app.DronesInformationPanel);
            app.CameraLabel.Text = 'Camera';
            app.DisconnectButton = uibutton(app.DronesInformationPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.LandButton = uibutton(app.DronesInformationPanel, 'push');
            app.LandButton.ButtonPushedFcn = createCallbackFcn(app, @LandButtonPushed2, true);
            app.LandButton.Text = 'Land';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GrapthTab = uitab(app.TabGroup);
            app.GrapthTab.Title = 'Grapth';
            app.Graph = uiaxes(app.GrapthTab);
            xlabel(app.Graph, 'X')
            ylabel(app.Graph, 'Y')
            zlabel(app.Graph, 'Yaw(Down)')
            app.DataValuesPanel = uipanel(app.GrapthTab);
            app.DataValuesPanel.Title = 'Data Values';
            app.XCheckBox = uicheckbox(app.DataValuesPanel);
            app.XCheckBox.ValueChangedFcn = createCallbackFcn(app, @XCheckBoxValueChanged, true);
            app.XCheckBox.Text = 'X';
            app.RollCheckBox = uicheckbox(app.DataValuesPanel);
            app.RollCheckBox.ValueChangedFcn = createCallbackFcn(app, @RollCheckBoxValueChanged, true);
            app.RollCheckBox.Text = 'Roll';
            app.YCheckBox = uicheckbox(app.DataValuesPanel);
            app.YCheckBox.ValueChangedFcn = createCallbackFcn(app, @YCheckBoxValueChanged, true);
            app.YCheckBox.Text = 'Y';
            app.ZCheckBox = uicheckbox(app.DataValuesPanel);
            app.ZCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZCheckBoxValueChanged, true);
            app.ZCheckBox.Text = 'Z';
            app.PitchCheckBox = uicheckbox(app.DataValuesPanel);
            app.PitchCheckBox.ValueChangedFcn = createCallbackFcn(app, @PitchCheckBoxValueChanged, true);
            app.PitchCheckBox.Text = 'Pitch';
            app.YawCheckBox = uicheckbox(app.DataValuesPanel);
            app.YawCheckBox.ValueChangedFcn = createCallbackFcn(app, @YawCheckBoxValueChanged, true);
            app.YawCheckBox.Text = 'Yaw';
            app.SpeedLabel = uilabel(app.DataValuesPanel);
            app.SpeedLabel.Text = 'Speed';
            app.OrientationLabel = uilabel(app.DataValuesPanel);
            app.OrientationLabel.Text = 'Orientation';
            app.HeightLabel = uilabel(app.DataValuesPanel);
            app.HeightLabel.Text = 'Height';
            app.HeightCheckBox = uicheckbox(app.DataValuesPanel);
            app.HeightCheckBox.ValueChangedFcn = createCallbackFcn(app, @HeightCheckBoxValueChanged, true);
            app.HeightCheckBox.Text = 'Height';
            app.VelocityCheckBox = uicheckbox(app.DataValuesPanel);
            app.VelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @VelocityCheckBoxValueChanged, true);
            app.VelocityCheckBox.Text = 'Velocity';
            app.ClearButton = uibutton(app.GrapthTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Icon = 'clear.png';
            app.ClearButton.Text = 'Clear';
            app.StopButton_2 = uibutton(app.GrapthTab, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Icon = 'stop.jpg';
            app.StopButton_2.Text = 'Stop';
            app.StartButton = uibutton(app.GrapthTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = 'Start.png';
            app.StartButton.Text = 'Start';
            app.SaveButton = uibutton(app.GrapthTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'SavePic.png';
            app.SaveButton.Text = 'Save';
            app.CameraStreamTab = uitab(app.TabGroup);
            app.CameraStreamTab.Title = 'Camera Stream';
            app.CameraCanvas = uiaxes(app.CameraStreamTab);
            app.CameraCanvas.XTick = [];
            app.CameraCanvas.YTick = [];
            app.StreamButton = uibutton(app.CameraStreamTab, 'push');
            app.StreamButton.ButtonPushedFcn = createCallbackFcn(app, @StreamButtonPushed, true);
            app.StreamButton.Text = 'Stream';
            app.StopButton = uibutton(app.CameraStreamTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.OpenascriptButton = uibutton(app.UIFigure, 'push');
            app.OpenascriptButton.ButtonPushedFcn = createCallbackFcn(app, @OpenascriptButtonPushed, true);
            app.OpenascriptButton.Text = 'Open a script';
            app.TextArea = uitextarea(app.UIFigure);
            app.DAnimationButton = uibutton(app.UIFigure, 'push');
            app.DAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @DAnimationButtonPushed, true);
            app.DAnimationButton.Text = '3D Animation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tello

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end