classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        EditField2                 matlab.ui.control.NumericEditField
        EditField2Label            matlab.ui.control.Label
        EditField                  matlab.ui.control.TextArea
        EditFieldTextAreaLabel     matlab.ui.control.Label
        CONNECTButton              matlab.ui.control.Button
        SnapButton                 matlab.ui.control.Button
        RecordButton               matlab.ui.control.Button
        StreamButton               matlab.ui.control.Button
        SpinLabel                  matlab.ui.control.Label
        SpinLeftBy45               matlab.ui.control.Button
        SpinRightBy45              matlab.ui.control.Button
        DistanceSpinner            matlab.ui.control.Spinner
        DistanceSpinnerLabel       matlab.ui.control.Label
        SpeedSpinner               matlab.ui.control.Spinner
        SpeedSpinnerLabel          matlab.ui.control.Label
        MoveLabel                  matlab.ui.control.Label
        AltitudeLabel              matlab.ui.control.Label
        Down                       matlab.ui.control.Button
        Up                         matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        Right                      matlab.ui.control.Button
        Back                       matlab.ui.control.Button
        Forward                    matlab.ui.control.Button
        Left                       matlab.ui.control.Button
        ABORTButton                matlab.ui.control.Button
        DronesInformationPanel     matlab.ui.container.Panel
        TakeoffButton              matlab.ui.control.Button
        LandButton                 matlab.ui.control.Button
        CameraEditField            matlab.ui.control.EditField
        CameraEditFieldLabel       matlab.ui.control.Label
        BatterLevelEditField       matlab.ui.control.NumericEditField
        BatterLevelEditFieldLabel  matlab.ui.control.Label
        StateEditField             matlab.ui.control.NumericEditField
        StateEditFieldLabel        matlab.ui.control.Label
        IDEditField                matlab.ui.control.NumericEditField
        IDLabel                    matlab.ui.control.Label
        NameEditField              matlab.ui.control.EditField
        NameEditFieldLabel         matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
        ContextMenu                matlab.ui.container.ContextMenu
        Menu                       matlab.ui.container.Menu
        Menu2                      matlab.ui.container.Menu
        ContextMenu2               matlab.ui.container.ContextMenu
        Menu_2                     matlab.ui.container.Menu
        Menu2_2                    matlab.ui.container.Menu
    end

    
    properties (Access = private)
        myTello;
        time = tic;
       speed;
      distance; % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TakeoffButton
        function TakeoffButtonPushed(app, event)
    
        takeoff(app.myTello);
        app.time;   
        end

        % Button pushed function: LandButton
        function LandButtonPushed(app, event)
            land(app.myTello);
            clear app.myTello;
        end

        % Button pushed function: CONNECTButton
        function CONNECTButtonPushed(app, event)
            app.myTello = ryze();

            
        end

        % Button pushed function: ABORTButton
        function ABORTButtonPushed(app, event)
            abort(app.myTello);
        end

        % Button pushed function: Forward
        function ForwardPushed(app, event)
            moveforward(app.myTello,'Distance',0.5, 'Speed',app.speed)
        end

        % Button pushed function: Left
        function LeftButtonPushed(app, event)
            moveleft(app.myTello,'Distance',0.5, 'Speed',app.speed)
        end

        % Button pushed function: Back
        function BackButtonPushed(app, event)
            moveback(app.myTello,'Distance',0.5, 'Speed',app.speed)
        end

        % Button pushed function: Right
        function RightButtonPushed(app, event)
            moveright(app.myTello,'Distance',0.5, 'Speed',app.speed)
        end

        % Button pushed function: Up
        function UpButtonPushed(app, event)
            moveup(app.myTello,'Distance',0.2, 'Speed',app.speed)
        end

        % Button pushed function: Down
        function DownButtonPushed(app, event)
            movedown(app.myTello,'Distance',0.2, 'Speed',app.speed)
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            x = readSpeed(app.myTello);
            y = readHeight(app.myTello);
            plot (app.UIAxes,x,y);
            
            
        end

        % Button pushed function: SpinLeftBy45
        function SpinLeftBy45ButtonPushed(app, event)
            turn(app.myTello,deg2rad(45));
        end

        % Button pushed function: SpinRightBy45
        function SpinRightBy45ButtonPushed(app, event)
            turn(app.myTello,deg2rad(-45));
        end

        % Value changing function: SpeedSpinner
        function SpeedSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.speed = changingValue;
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            cameraObj = camera(app.myTello);
            preview(cameraObj);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Drone Orientation v/s Time')
            xlabel(app.UIAxes, 'Time (in s)')
            ylabel(app.UIAxes, 'Orientation in degree')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.DronesInformationPanel = uipanel(app.UIFigure);
            app.DronesInformationPanel.Title = 'Drone''s Information';
            app.NameEditFieldLabel = uilabel(app.DronesInformationPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.DronesInformationPanel, 'text');
            app.IDLabel = uilabel(app.DronesInformationPanel);
            app.IDLabel.Text = ' ID  ';
            app.IDEditField = uieditfield(app.DronesInformationPanel, 'numeric');
            app.StateEditFieldLabel = uilabel(app.DronesInformationPanel);
            app.StateEditFieldLabel.Text = 'State';
            app.StateEditField = uieditfield(app.DronesInformationPanel, 'numeric');
            app.BatterLevelEditFieldLabel = uilabel(app.DronesInformationPanel);
            app.BatterLevelEditFieldLabel.Text = 'BatterLevel';
            app.BatterLevelEditField = uieditfield(app.DronesInformationPanel, 'numeric');
            app.CameraEditFieldLabel = uilabel(app.DronesInformationPanel);
            app.CameraEditFieldLabel.Text = 'Camera';
            app.CameraEditField = uieditfield(app.DronesInformationPanel, 'text');
            app.LandButton = uibutton(app.DronesInformationPanel, 'push');
            app.LandButton.ButtonPushedFcn = createCallbackFcn(app, @LandButtonPushed, true);
            app.LandButton.Text = 'Land';
            app.TakeoffButton = uibutton(app.DronesInformationPanel, 'push');
            app.TakeoffButton.ButtonPushedFcn = createCallbackFcn(app, @TakeoffButtonPushed, true);
            app.TakeoffButton.Text = 'Take off';
            app.ABORTButton = uibutton(app.UIFigure, 'push');
            app.ABORTButton.ButtonPushedFcn = createCallbackFcn(app, @ABORTButtonPushed, true);
            app.ABORTButton.Text = 'ABORT';
            app.Left = uibutton(app.UIFigure, 'push');
            app.Left.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.Left.Icon = '1494822-200.png';
            app.Left.Text = '';
            app.Forward = uibutton(app.UIFigure, 'push');
            app.Forward.ButtonPushedFcn = createCallbackFcn(app, @ForwardPushed, true);
            app.Forward.Icon = 'arrow+circle+arrow+up+arrowupcircle+top+up+icon+icon-1320185730886164747.png';
            app.Forward.Text = '';
            app.Back = uibutton(app.UIFigure, 'push');
            app.Back.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.Back.Icon = 'arrow+circle+arrow+down+arrowdowncircle+bottom+down+icon+icon-1320185730369221337.png';
            app.Back.Text = '';
            app.Right = uibutton(app.UIFigure, 'push');
            app.Right.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.Right.Icon = '1494813-200.png';
            app.Right.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Up = uibutton(app.UIFigure, 'push');
            app.Up.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.Up.Icon = 'arrow+circle+arrow+up+arrowupcircle+top+up+icon+icon-1320185730886164747.png';
            app.Up.Text = '';
            app.Down = uibutton(app.UIFigure, 'push');
            app.Down.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.Down.Icon = 'arrow+circle+arrow+down+arrowdowncircle+bottom+down+icon+icon-1320185730369221337.png';
            app.Down.Text = '';
            app.AltitudeLabel = uilabel(app.UIFigure);
            app.AltitudeLabel.Text = 'Altitude';
            app.MoveLabel = uilabel(app.UIFigure);
            app.MoveLabel.Text = 'Move';
            app.SpeedSpinnerLabel = uilabel(app.UIFigure);
            app.SpeedSpinnerLabel.Text = 'Speed';
            app.SpeedSpinner = uispinner(app.UIFigure);
            app.SpeedSpinner.Step = 0.2;
            app.SpeedSpinner.ValueChangingFcn = createCallbackFcn(app, @SpeedSpinnerValueChanging, true);
            app.DistanceSpinnerLabel = uilabel(app.UIFigure);
            app.DistanceSpinnerLabel.Text = 'Distance';
            app.DistanceSpinner = uispinner(app.UIFigure);
            app.SpinRightBy45 = uibutton(app.UIFigure, 'push');
            app.SpinRightBy45.ButtonPushedFcn = createCallbackFcn(app, @SpinRightBy45ButtonPushed, true);
            app.SpinRightBy45.Icon = '1494813-200.png';
            app.SpinRightBy45.Text = '';
            app.SpinLeftBy45 = uibutton(app.UIFigure, 'push');
            app.SpinLeftBy45.ButtonPushedFcn = createCallbackFcn(app, @SpinLeftBy45ButtonPushed, true);
            app.SpinLeftBy45.Icon = '1494822-200.png';
            app.SpinLeftBy45.Text = '';
            app.SpinLabel = uilabel(app.UIFigure);
            app.SpinLabel.Text = 'Spin';
            app.StreamButton = uibutton(app.UIFigure, 'push');
            app.StreamButton.Text = 'Stream';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.Text = 'Record';
            app.SnapButton = uibutton(app.UIFigure, 'push');
            app.SnapButton.Text = 'Snap';
            app.CONNECTButton = uibutton(app.UIFigure, 'push');
            app.CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @CONNECTButtonPushed, true);
            app.CONNECTButton.Text = 'CONNECT';
            app.EditFieldTextAreaLabel = uilabel(app.UIFigure);
            app.EditFieldTextAreaLabel.Text = 'Edit Field';
            app.EditField = uitextarea(app.UIFigure);
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.Left.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            % Assign app.ContextMenu2
            app.Forward.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end